classdef MCDI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MaquinaCDIUIFigure              matlab.ui.Figure
        RelatorioMenu                   matlab.ui.container.Menu
        SobreMenu                       matlab.ui.container.Menu
        AutoresMenu                     matlab.ui.container.Menu
        ISECMenu                        matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        yFxTab                          matlab.ui.container.Tab
        labelResultadoIntegral          matlab.ui.control.Label
        eResultadoIntegral              matlab.ui.control.NumericEditField
        UITable                         matlab.ui.control.Table
        Panel_3                         matlab.ui.container.Panel
        GridCheckBox                    matlab.ui.control.CheckBox
        IntegracaoDerivacaoSwitch       matlab.ui.control.Switch
        IntegraoNumricaLabel            matlab.ui.control.Label
        eA_2                            matlab.ui.control.NumericEditField
        aEditFieldLabel_2               matlab.ui.control.Label
        IntegracaoNumericaListGroup     matlab.ui.control.ListBox
        DerivacaoNumericaButtonGroup    matlab.ui.container.ButtonGroup
        DiferenasFinitas2aDerivadaButton  matlab.ui.control.RadioButton
        DiferenasFinitasCentradasButton  matlab.ui.control.RadioButton
        DiffFinitasRegem3PontosButton   matlab.ui.control.RadioButton
        DiffFinitasProgem3PontosButton  matlab.ui.control.RadioButton
        DiferenasFinitasRegressivasButton  matlab.ui.control.RadioButton
        rbDFP                           matlab.ui.control.RadioButton
        Label_8                         matlab.ui.control.Label
        eB_2                            matlab.ui.control.NumericEditField
        bEditFieldLabel_2               matlab.ui.control.Label
        eH                              matlab.ui.control.NumericEditField
        hLabel                          matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        xLabel_2                        matlab.ui.control.Label
        ExecutarButton_2                matlab.ui.control.Button
        eFx                             matlab.ui.control.EditField
        fxLabel                         matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        zFxyTab                         matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        OrdemDerivadaSwitch             matlab.ui.control.Switch
        DerivadaemordemaSwitchLabel     matlab.ui.control.Label
        DerivadaParcialLabel            matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        eD                              matlab.ui.control.NumericEditField
        eH2                             matlab.ui.control.NumericEditField
        eC                              matlab.ui.control.NumericEditField
        dLabel                          matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        h2Label                         matlab.ui.control.Label
        cLabel                          matlab.ui.control.Label
        yLabel                          matlab.ui.control.Label
        eB                              matlab.ui.control.NumericEditField
        bEditFieldLabel                 matlab.ui.control.Label
        eH1                             matlab.ui.control.NumericEditField
        h1EditFieldLabel                matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        eA                              matlab.ui.control.NumericEditField
        aEditFieldLabel                 matlab.ui.control.Label
        xLabel                          matlab.ui.control.Label
        ExecutarButton                  matlab.ui.control.Button
        eFxy                            matlab.ui.control.EditField
        fxyEditFieldLabel               matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        LabelHarmonica                  matlab.ui.control.Label
        LabelLaplaceEDP                 matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end


    methods (Access = public)

        function Yn = isHarmonica(~,f)
            syms z(x,y);
            Yn = (simplify(diff(f,x,2) + diff(f,y,2)) == 0);
        end

        function [x,y,dydx] = NDerivadaDFP(~,f,a,b,h,y)
            x = a:h:b;
            n = length(x);
            if nargin == 5
                y = f(x);
            end
            dydx = zeros(1,n);
            for i = 1:n-1
                dydx(i) = (y(i+1)-y(i))/h;
            end
            %usar regressiva para calcular o ultimo ponto
            dydx(n) = (y(n)-y(n-1))/h;
        end

        function [x,y,dydx] = NDerivadaDFR(~,f,a,b,h,y)
            x = a:h:b;
            n = length(x);
            if nargin == 5
                y = f(x);
            end
            dydx = zeros(1,n);
            dydx(1)=(y(2)-y(1))/h;
            for i=2:n%-1
                dydx(i) = (y(i)-y(i-1))/h;
            end
            %usar Progressiva para calcular o ultimo ponto
            % dydx(n) = (y(n+1)-y(n))/h;
        end

        function [x,y,dydx] = NDerivadaDFP3P(~,f,a,b,h,y)
            x = a:h:b;
            n = length(x);
            if nargin == 5
                y = f(x);
            end
            dydx = zeros(1,n);
            for i=2:n-2
                dydx(i) = (-3*y(i)+4*y(i+1)-y(i+2))/(2*h);
            end
            dydx(n-1)=(y(n-3) - 4*y(n-2) + 3*y(n-1))/(2*h);
            dydx(n)=(y(n-2) - 4*y(n-1) + 3*y(n))/(2*h);
        end

        function [x,y,dydx] = NDerivadaDFR3(~,f,a,b,h,y)
            x=a:h:b;
            n=length(x);
            if nargin==5
                y=f(x);
            end
            dydx=zeros(1,n);
            dydx(1)=(-3*y(1) + 4*y(2) - y(3))/(2*h);
            dydx(2)=(-3*y(2) + 4*y(3) - y(4))/(2*h);
            for i=3:n
                dydx(i)=(y(i-2) - 4*y(i-1) + 3*y(i))/(2*h);
            end
        end

        function [x,y,dydx] = NDerivadaDFC3P(~,f,a,b,h,y)
            x=a:h:b;
            n=length(x);
            if nargin==5
                y=f(x);
            end
            dydx=zeros(1,n);
            dydx(1)=(-3*y(1) + 4*y(2) - y(3))/(2*h);
            for i=2:n-1
                dydx(i)=(y(i+1)-y(i-1))/(2*h);
            end
            dydx(n)=(y(n-2) - 4*y(n-1) + 3*y(n))/(2*h);
        end

        function [x,y,dydx] = SegundaDerivada(~,f,a,b,h,y)
            x=a:h:b;
            n=length(x);
            if nargin==5
                y=f(x);
            end
            dydx=zeros(1,n);

            dydx1=(-3*y(1) + 4*y(2) - y(3))/(2*h);
            dydx2=(-3*y(2) + 4*y(3) - y(4))/(2*h);
            dydx3=(-3*y(3) + 4*y(4) - y(5))/(2*h);

            dydx(1)=(-3*dydx1 + 2*dydx2 - 3*dydx3)/(2*h);

            for i=2:n-1
                dydx(i)=(y(i+1)-2*y(i)-y(i-1))/(2*h);
            end

            dydx1=(-3*y(n-4) + 4*y(n-3) - y(n-2))/(2*h);
            dydx2=(-3*y(n-3) + 4*y(n-2) - y(n-1))/(2*h);
            dydx3=(-3*y(n-2) + 4*y(n-1) - y(n))/(2*h);

            dydx(n)=(-3*dydx1 + 2*dydx2 - 3*dydx3)/(2*h);
        end

        function Tsol = RTrapezios(~,f,a,b,h)
            t = a:h:b;
            s = 0;
            n = length(t);
            for i=1:n-1
                s = s + f(t(i));
            end
            Tsol = h/2*(f(a)+2*s+f(b));
        end

        function out_S = RSimpson(~,f,a,b,h)
            t = a:h:b;
            s = 0;
            n = length(t);
            for i=1:n-1
                %se o resto da divisão por 2
                % for 0, então i é par
                if ~(mod(i, 2))
                    s = s + 2*f(t(i));
                else
                    s = s + 4*f(t(i));
                end
            end
            out_S = h/3*(f(a)+s+f(b));
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ExecutarButton_2Pushed(app, []);
        end

        % Button pushed function: ExecutarButton
        function ExecutarButtonPushed(app, event)
            syms z(x,y)

            % Leitura dos dados
            strF = app.eFxy.Value;
            f = str2func(vectorize(strcat("@(x,y)", " ", strF)));
            a = app.eA.Value;
            h1 = app.eH1.Value;
            b = app.eB.Value;
            c = app.eC.Value;
            h2 = app.eH2.Value;
            d = app.eD.Value;

            try
                try
                    z = f(x,y);
                    app.eFxy.BackgroundColor = [1,1,1];
                catch Me
                    app.eFxy.BackgroundColor = [1,0,0];
                    throw(MException("MATLAB:executar:badFxy", "Introduza uma função nas variáveis x e y"));
                end


                if(a>b || a==b)
                    app.eA.BackgroundColor = 'r';
                    app.eB.BackgroundColor = 'r';
                    if(a == b)
                        throw(MException('MATLAB:BadFunction','Valor de A tem de ser DIFERENTE que B'));
                    else
                        throw(MException('MATLAB:BadFunction','Valor de A tem de ser MENOR que B'));
                    end
                else
                    app.eA.BackgroundColor = "w";
                    app.eB.BackgroundColor = "w";
                end

                if(h1<(b-a))
                    app.eH1.BackgroundColor = 'r';
                    throw(MException('MATLAB:BadFunction','Valor de H1 tem de ser menor'));
                else
                    app.eH1.BackgroundColor = "w";
                end

                if(c>d || c==d)
                    app.eC.BackgroundColor = 'r';
                    app.eD.BackgroundColor = 'r';
                    if(c == d)
                        throw(MException('MATLAB:BadFunction','Valor de C tem de ser DIFERENTE que B'));
                    else
                        throw(MException('MATLAB:BadFunction','Valor de C tem de ser MENOR que D'));
                    end
                else
                    app.eC.BackgroundColor = "w";
                    app.eD.BackgroundColor = "w";
                end

                if(h2<(b-a))
                    app.eH2.BackgroundColor = 'r';
                    throw(MException('MATLAB:BadFunction','Valor de H2 tem de ser menor'));
                else
                    app.eH2.BackgroundColor = "w";
                end

                OrdemDerivadaChoice = app.OrdemDerivadaSwitch.Value;

                if OrdemDerivadaChoice == "x"
                    %Calcula a diferencial em ordem a x
                    % e apresenta em latex para o user
                    dfdxSym = diff(f,x);
                    LaTexDParcial = strcat("$",...
                        "\displaystyle \frac{\partial f}{\partial x} =",...
                        latex(dfdxSym),"$");
                elseif OrdemDerivadaChoice == "y"
                    %Calcula a diferencial em ordem a y
                    % e apresenta em latex para o user
                    dfdySym = diff(f,y);
                    LaTexDParcial = strcat("$",...
                        "\displaystyle \frac{\partial f}{\partial y} =",...
                        latex(dfdySym),"$");
                end
                app.DerivadaParcialLabel.Text = LaTexDParcial;

                % Representação gráfica
                vx = a:h1:b;                % vetor das abcissas
                vy = c:h2:d;                % vetor das ordenadas
                [x,y] = meshgrid(vx,vy);    % malha de pontos a 2D
                z = f(x,y);                 % vetor das cotas
                surfc(app.UIAxes,x,y,z)

                LaplaceEq = "$\frac{\partial^2 f}{\partial x^2} + \frac{\partial^2 f}{\partial y^2} = 0$";
                eq2 = "$\frac{\partial^2 f}{\partial x^2} + \frac{\partial^2 f}{\partial y^2} \neq 0$";

                if isHarmonica(app, f)
                    textoHarmonica = "A função é harmónica";
                    % corHarmonica = "g";
                    corHarmonica = [0 0.5 0]; % darkgreen

                    app.LabelLaplaceEDP.Text = LaplaceEq;
                else
                    textoHarmonica = "A função não é harmónica";
                    corHarmonica = "r";

                    app.LabelLaplaceEDP.Text = eq2;
                end

                app.LabelHarmonica.Text = textoHarmonica;
                app.LabelHarmonica.FontWeight = "bold";
                app.LabelHarmonica.FontColor = corHarmonica;

            catch Me
                errordlg(Me.message, "Erro", "modal");
            end
        end

        % Button pushed function: ExecutarButton_2
        function ExecutarButton_2Pushed(app, event)
            syms y(x)

            % Leitura dos dados
            strFx = app.eFx.Value;
            f = str2func(vectorize(strcat("@(x)", " ", strFx)));
            a = app.eA_2.Value;
            h = app.eH.Value;
            b = app.eB_2.Value;

            if(a>b || a==b)
                app.eA.BackgroundColor = 'r';
                app.eB.BackgroundColor = 'r';
                if(a == b)
                    throw(MException('MATLAB:BadFunction','Valor de A tem de ser DIFERENTE que B'));
                else
                    throw(MException('MATLAB:BadFunction','Valor de A tem de ser MENOR que B'));
                end
            else
                app.eA.BackgroundColor = "w";
                app.eB.BackgroundColor = "w";
            end

            if(h<(b-a))
                app.eH.BackgroundColor = 'r';
                throw(MException('MATLAB:BadFunction','Valor de H tem de ser menor'));
            else
                app.eH.BackgroundColor = "w";
            end

            try
                dydxSym = diff(f,x);
                dfdx = @(x) eval(vectorize(char(dydxSym)));
            catch Me
                errordlg(Me.message,"ERRO","modal")
            end

            DerivadaOrIntegradaChoice = app.IntegracaoDerivacaoSwitch.Value;

            if DerivadaOrIntegradaChoice == "Derivação"
                MDerivacaoNumericaChoice = app.DerivacaoNumericaButtonGroup.SelectedObject.Text;

                switch MDerivacaoNumericaChoice
                    case 'Diferenças Finitas Progessivas'
                        [x,y,dydxNDF] = NDerivadaDFP(app,f,a,b,h);
                    case 'Diferenças Finitas Regressivas'
                        [x,y,dydxNDF] = NDerivadaDFR(app,f,a,b,h);
                    case 'Diff. Finitas Prog. em 3 Pontos'
                        [x,y,dydxNDF] = NDerivadaDFP3P(app,f,a,b,h);
                    case 'Diff. Finitas Reg. em 3 Pontos'
                        [x,y,dydxNDF] = NDerivadaDFR3(app,f,a,b,h);
                    case 'Diferenças Finitas Centradas'
                        [x,y,dydxNDF] = NDerivadaDFC3P(app,f,a,b,h);
                    case 'Diferenças Finitas 2a Derivada'
                        [x,y,dydxNDF] = SegundaDerivada(app,f,a,b,h);
                    otherwise
                        [x,y,dydxNDF] = NDerivadaDFP(app,f,a,b,h);
                end

                dydxExata = dfdx(x);
                erroDFP = abs(dydxExata-dydxNDF);
                tabelaResultados = [x.',y.',dydxExata.',dydxNDF.',erroDFP.'];

                cla(app.UIAxes2)
                app.UITable.Visible = "on";
                app.eResultadoIntegral.Visible = "off";
                app.labelResultadoIntegral.Visible = "off";

                plot(app.UIAxes2,x,y,"-r+");
                hold(app.UIAxes2,"on");
                grid(app.UIAxes2, app.GridCheckBox.Value);
                plot(app.UIAxes2,x,dydxExata,"-bo");
                plot(app.UIAxes2,x,dydxNDF,"-g+");
                hold(app.UIAxes2,"off");
                legend(app.UIAxes2,["f(x)","dydxExata","dydxDFP"]);

                app.UITable.Data = tabelaResultados;
                app.UITable.ColumnName = ["x", "y","dydxExata","dydxDFP", "erroDFP"];
            elseif DerivadaOrIntegradaChoice == "Integração"

                integracaoChoice = app.IntegracaoNumericaListGroup.Value;
                app.UITable.Visible = "off";
                app.eResultadoIntegral.Visible = "on";
                app.labelResultadoIntegral.Visible = "on";

                switch integracaoChoice
                    case 'Regra dos Trapézios'
                        T = RTrapezios(app,f,a,b,h);
                        app.eResultadoIntegral.Value = T;
                        [x,y,~] = NDerivadaDFP(app,f,a,b,h);
                        area(app.UIAxes2,x,y,"FaceColor",[0.4940 0.1840 0.5560]);
                        legend(app.UIAxes2,'Area Integral');

                    case 'Regra de Simpson'
                        S = RSimpson(app,f,a,b,h);
                        app.eResultadoIntegral.Value = S;
                        [x,y,~] = NDerivadaDFP(app,f,a,b,h);
                        area(app.UIAxes2,x,y,"FaceColor",[0.9290 0.6940 0.1250]);
                        legend(app.UIAxes2,'Area Integral');
                end
            end
        end

        % Button down function: zFxyTab
        function zFxyTabButtonDown(app, event)
            ExecutarButtonPushed(app, event);
        end

        % Button down function: yFxTab
        function yFxTabButtonDown(app, event)
            ExecutarButton_2Pushed(app, event);
        end

        % Clicked callback: IntegracaoNumericaListGroup
        function IntegracaoNumericaListGroupClicked(app, event)
            app.IntegracaoDerivacaoSwitch.Value = "Integração";
            ExecutarButton_2Pushed(app, event);
        end

        % Selection changed function: DerivacaoNumericaButtonGroup
        function DerivacaoNumericaButtonGroupSelectionChanged(app, event)
            app.IntegracaoDerivacaoSwitch.Value = "Derivação";
            ExecutarButton_2Pushed(app, event);
        end

        % Menu selected function: RelatorioMenu
        function RelatorioMenuSelected(app, event)
            open("MNDI_PVI_Ana_Rita_Conceicao_Pessoa_2023112690_Joao_Francisco_de_Matos_Claro_2017010293_Engenharia_Informatica.pdf");
        end

        % Menu selected function: SobreMenu
        function SobreMenuSelected(app, event)
            
        end

        % Menu selected function: AutoresMenu
        function AutoresMenuSelected(app, event)
            autores
        end

        % Menu selected function: ISECMenu
        function ISECMenuSelected(app, event)
            web("www.isec.pt")
        end

        % Value changed function: GridCheckBox
        function GridCheckBoxValueChanged(app, event)
            grid(app.UIAxes2, app.GridCheckBox.Value);
        end

        % Value changed function: OrdemDerivadaSwitch
        function OrdemDerivadaSwitchValueChanged(app, event)
            ExecutarButtonPushed(app, event);            
        end

        % Value changed function: IntegracaoDerivacaoSwitch
        function IntegracaoDerivacaoSwitchValueChanged(app, event)
            ExecutarButton_2Pushed(app, event);
        end

        % Value changed function: IntegracaoNumericaListGroup
        function IntegracaoNumericaListGroupValueChanged(app, event)
            ExecutarButton_2Pushed(app, event);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MaquinaCDIUIFigure = uifigure('Visible', 'off');
            app.MaquinaCDIUIFigure.Name = 'MaquinaCDI';
            app.RelatorioMenu = uimenu(app.MaquinaCDIUIFigure);
            app.RelatorioMenu.MenuSelectedFcn = createCallbackFcn(app, @RelatorioMenuSelected, true);
            app.RelatorioMenu.Text = 'Relatorio';
            app.SobreMenu = uimenu(app.MaquinaCDIUIFigure);
            app.SobreMenu.MenuSelectedFcn = createCallbackFcn(app, @SobreMenuSelected, true);
            app.SobreMenu.Text = 'Sobre';
            app.AutoresMenu = uimenu(app.SobreMenu);
            app.AutoresMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoresMenuSelected, true);
            app.AutoresMenu.Text = 'Autores';
            app.ISECMenu = uimenu(app.SobreMenu);
            app.ISECMenu.MenuSelectedFcn = createCallbackFcn(app, @ISECMenuSelected, true);
            app.ISECMenu.Text = 'ISEC';
            app.TabGroup = uitabgroup(app.MaquinaCDIUIFigure);
            app.yFxTab = uitab(app.TabGroup);
            app.yFxTab.Title = 'Função real de variável real y = f(x)';
            app.yFxTab.ButtonDownFcn = createCallbackFcn(app, @yFxTabButtonDown, true);
            app.UIAxes2 = uiaxes(app.yFxTab);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Panel_3 = uipanel(app.yFxTab);
            app.fxLabel = uilabel(app.Panel_3);
            app.fxLabel.Text = 'f(x) = ';
            app.eFx = uieditfield(app.Panel_3, 'text');
            app.eFx.Value = 'sin(x)';
            app.ExecutarButton_2 = uibutton(app.Panel_3, 'push');
            app.ExecutarButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButton_2Pushed, true);
            app.ExecutarButton_2.Text = 'Executar';
            app.xLabel_2 = uilabel(app.Panel_3);
            app.xLabel_2.Text = 'x = ';
            app.Label_7 = uilabel(app.Panel_3);
            app.Label_7.Text = ':';
            app.hLabel = uilabel(app.Panel_3);
            app.hLabel.Text = 'h';
            app.eH = uieditfield(app.Panel_3, 'numeric');
            app.eH.Value = 0.2;
            app.bEditFieldLabel_2 = uilabel(app.Panel_3);
            app.bEditFieldLabel_2.Text = 'b';
            app.eB_2 = uieditfield(app.Panel_3, 'numeric');
            app.eB_2.Value = 7;
            app.Label_8 = uilabel(app.Panel_3);
            app.Label_8.Text = ':';
            app.DerivacaoNumericaButtonGroup = uibuttongroup(app.Panel_3);
            app.DerivacaoNumericaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DerivacaoNumericaButtonGroupSelectionChanged, true);
            app.DerivacaoNumericaButtonGroup.Title = 'Derivação Numérica';
            app.rbDFP = uiradiobutton(app.DerivacaoNumericaButtonGroup);
            app.rbDFP.Text = 'Diferenças Finitas Progessivas';
            app.rbDFP.Value = true;
            app.DiferenasFinitasRegressivasButton = uiradiobutton(app.DerivacaoNumericaButtonGroup);
            app.DiferenasFinitasRegressivasButton.Text = 'Diferenças Finitas Regressivas';
            app.DiffFinitasProgem3PontosButton = uiradiobutton(app.DerivacaoNumericaButtonGroup);
            app.DiffFinitasProgem3PontosButton.Text = 'Diff. Finitas Prog. em 3 Pontos';
            app.DiffFinitasRegem3PontosButton = uiradiobutton(app.DerivacaoNumericaButtonGroup);
            app.DiffFinitasRegem3PontosButton.Text = 'Diff. Finitas Reg. em 3 Pontos';
            app.DiferenasFinitasCentradasButton = uiradiobutton(app.DerivacaoNumericaButtonGroup);
            app.DiferenasFinitasCentradasButton.Text = 'Diferenças Finitas Centradas';
            app.DiferenasFinitas2aDerivadaButton = uiradiobutton(app.DerivacaoNumericaButtonGroup);
            app.DiferenasFinitas2aDerivadaButton.Text = 'Diferenças Finitas 2a Derivada';
            app.IntegracaoNumericaListGroup = uilistbox(app.Panel_3);
            app.IntegracaoNumericaListGroup.Items = {'Regra dos Trapézios', 'Regra de Simpson'};
            app.IntegracaoNumericaListGroup.ValueChangedFcn = createCallbackFcn(app, @IntegracaoNumericaListGroupValueChanged, true);
            app.IntegracaoNumericaListGroup.ClickedFcn = createCallbackFcn(app, @IntegracaoNumericaListGroupClicked, true);
            app.IntegracaoNumericaListGroup.Value = 'Regra dos Trapézios';
            app.aEditFieldLabel_2 = uilabel(app.Panel_3);
            app.aEditFieldLabel_2.Text = 'a';
            app.eA_2 = uieditfield(app.Panel_3, 'numeric');
            app.IntegraoNumricaLabel = uilabel(app.Panel_3);
            app.IntegraoNumricaLabel.Text = 'Integração Numérica';
            app.IntegracaoDerivacaoSwitch = uiswitch(app.Panel_3, 'slider');
            app.IntegracaoDerivacaoSwitch.Items = {'Derivação', 'Integração'};
            app.IntegracaoDerivacaoSwitch.ValueChangedFcn = createCallbackFcn(app, @IntegracaoDerivacaoSwitchValueChanged, true);
            app.IntegracaoDerivacaoSwitch.Value = 'Derivação';
            app.GridCheckBox = uicheckbox(app.Panel_3);
            app.GridCheckBox.ValueChangedFcn = createCallbackFcn(app, @GridCheckBoxValueChanged, true);
            app.GridCheckBox.Text = 'Grid';
            app.GridCheckBox.Value = true;
            app.UITable = uitable(app.yFxTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.eResultadoIntegral = uieditfield(app.yFxTab, 'numeric');
            app.eResultadoIntegral.Editable = 'off';
            app.eResultadoIntegral.Visible = 'off';
            app.labelResultadoIntegral = uilabel(app.yFxTab);
            app.labelResultadoIntegral.Visible = 'off';
            app.labelResultadoIntegral.Text = 'Resultado Aproximado: ';
            app.zFxyTab = uitab(app.TabGroup);
            app.zFxyTab.Title = 'Função real de 2 variáveis reais z = f(x,y) ';
            app.zFxyTab.ButtonDownFcn = createCallbackFcn(app, @zFxyTabButtonDown, true);
            app.UIAxes = uiaxes(app.zFxyTab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.zFxyTab);
            app.Panel_2 = uipanel(app.Panel);
            app.LabelLaplaceEDP = uilabel(app.Panel_2);
            app.LabelLaplaceEDP.Interpreter = 'latex';
            app.LabelLaplaceEDP.Text = '$\frac{\partial^2 f}{\partial x^2} + \frac{\partial^2 f}{\partial y^2} \neq 0 $';
            app.LabelHarmonica = uilabel(app.Panel_2);
            app.LabelHarmonica.Text = 'A função não é Harmonica';
            app.fxyEditFieldLabel = uilabel(app.Panel);
            app.fxyEditFieldLabel.Text = 'f(x,y) = ';
            app.eFxy = uieditfield(app.Panel, 'text');
            app.eFxy.Value = 'x^2+y^2';
            app.ExecutarButton = uibutton(app.Panel, 'push');
            app.ExecutarButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.ExecutarButton.Text = 'Executar';
            app.xLabel = uilabel(app.Panel);
            app.xLabel.Text = 'x = ';
            app.aEditFieldLabel = uilabel(app.Panel);
            app.aEditFieldLabel.Text = 'a';
            app.eA = uieditfield(app.Panel, 'numeric');
            app.eA.Value = -2;
            app.Label = uilabel(app.Panel);
            app.Label.Text = ':';
            app.h1EditFieldLabel = uilabel(app.Panel);
            app.h1EditFieldLabel.Text = 'h1';
            app.eH1 = uieditfield(app.Panel, 'numeric');
            app.eH1.Value = 0.2;
            app.bEditFieldLabel = uilabel(app.Panel);
            app.bEditFieldLabel.Text = 'b';
            app.eB = uieditfield(app.Panel, 'numeric');
            app.eB.Value = 2;
            app.yLabel = uilabel(app.Panel);
            app.yLabel.Text = 'y = ';
            app.cLabel = uilabel(app.Panel);
            app.cLabel.Text = 'c';
            app.h2Label = uilabel(app.Panel);
            app.h2Label.Text = 'h2';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = ':';
            app.dLabel = uilabel(app.Panel);
            app.dLabel.Text = 'd';
            app.eC = uieditfield(app.Panel, 'numeric');
            app.eC.Value = -2;
            app.eH2 = uieditfield(app.Panel, 'numeric');
            app.eH2.Value = 0.2;
            app.eD = uieditfield(app.Panel, 'numeric');
            app.eD.Value = 2;
            app.Label_5 = uilabel(app.Panel);
            app.Label_5.Text = ':';
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = ':';
            app.DerivadaParcialLabel = uilabel(app.Panel);
            app.DerivadaParcialLabel.Interpreter = 'latex';
            app.DerivadaParcialLabel.Text = 'DerivadaParcial';
            app.DerivadaemordemaSwitchLabel = uilabel(app.Panel);
            app.DerivadaemordemaSwitchLabel.Text = 'Derivada em ordem a: ';
            app.OrdemDerivadaSwitch = uiswitch(app.Panel, 'slider');
            app.OrdemDerivadaSwitch.Items = {'x', 'y'};
            app.OrdemDerivadaSwitch.ValueChangedFcn = createCallbackFcn(app, @OrdemDerivadaSwitchValueChanged, true);
            app.OrdemDerivadaSwitch.Value = 'x';
            % Show the figure after all components are created
            app.MaquinaCDIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MCDI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MaquinaCDIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MaquinaCDIUIFigure)
        end
    end
end